classdef voicemod < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        AudioSourceLabel      matlab.ui.control.Label
        UploadWavFileButton   matlab.ui.control.Button
        RecordWavFileButton   matlab.ui.control.Button
        RecordingLampLabel    matlab.ui.control.Label
        RecordingLamp         matlab.ui.control.Lamp
        CenterPanel           matlab.ui.container.Panel
        PlayAudioButton       matlab.ui.control.Button
        WaveformLabel         matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        StopPlaybackButton    matlab.ui.control.Button
        RightPanel            matlab.ui.container.Panel
        RoughnessSliderLabel  matlab.ui.control.Label
        RoughnessSlider       matlab.ui.control.Slider
        RobotSliderLabel      matlab.ui.control.Label
        RobotSlider           matlab.ui.control.Slider
        MaleSliderLabel       matlab.ui.control.Label
        MaleSlider            matlab.ui.control.Slider
        VoiceControlsLabel    matlab.ui.control.Label
        YoungSliderLabel      matlab.ui.control.Label
        YoungSlider           matlab.ui.control.Slider
        GenderSliderLabel_2   matlab.ui.control.Label
        YoungSliderLabel_2    matlab.ui.control.Label
        ResetButton           matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        inputfile= 'input.wav' % Description
        outputfile= 'output.wav'
    end
    
    methods (Access = public)


    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadWavFileButton
        function UploadWavFileButtonPushed(app, event)
            filename= uigetfile;
            [DAFx_out, FS]= audioread(filename);
            x = DAFx_out(:,1);
            audiowrite('input.wav', x, FS);
            audiowrite('output.wav', x, FS);
            audiowrite('original.wav',x, FS);
            disp(filename);
            %play the clip
            clip= audioplayer(DAFx_out,FS);
            clear sound;
            %display the file
            DAFx_out = DAFx_out(:,1);
            dt = 1/FS;
            t = 0:dt:(length(DAFx_out)*dt)-dt;
            plot(app.UIAxes_2,t,DAFx_out); xlabel(app.UIAxes_2,'Seconds'); ylabel(app.UIAxes_2,'Amplitude');
            %plot(app.UIAxes_2, periodogram(DAFx_out,'psd','Fs',FS,'NFFT',length(DAFx_out)));
            %sound(DAFx_out, FS);
        end

        % Value changed function: MaleSlider
        function MaleSliderValueChanged(app, event)
            %this function changes the perceived gender of the speaker by
            %changing pitch and timbre scaling
            value = app.MaleSlider.Value;
            [DAFx_out, FS]=audioread('input.wav');
            if value>1 %change to female voice
                timbremapping = [ 0 4000 FS/2; % input frequency
                    0 5000 FS/2 ];
            else %change to male voice
                timbremapping = [ 0 5000 FS/2; % input frequency
                    0 4000 FS/2 ]; % output frequency
            end
            [DAFx_out, FS]= gender_function('input.wav', value, timbremapping);
            clip= audioplayer(DAFx_out,FS);
            audiowrite('output.wav', DAFx_out, FS);
            %plot the new waveform
            dt = 1/FS;
            t = 0:dt:(length(DAFx_out)*dt)-dt;
            plot(app.UIAxes,t,DAFx_out); xlabel(app.UIAxes,'Seconds'); ylabel(app.UIAxes,'Amplitude');
            %
            disp('finished change');
            clear sound;
            
        end

        % Value changed function: RobotSlider
        function RobotSliderValueChanged(app, event)
            value = app.RobotSlider.Value;
            [DAFx_out, FS]= robot_function('input.wav', value);
            clip= audioplayer(DAFx_out,FS);
            audiowrite('output.wav', DAFx_out, FS);
            %plot the new waveform
            dt = 1/FS;
            t = 0:dt:(length(DAFx_out)*dt)-dt;
            plot(app.UIAxes,t,DAFx_out); xlabel(app.UIAxes,'Seconds'); ylabel(app.UIAxes,'Amplitude');
            disp('finished change');
            clear sound;
            
        end

        % Value changed function: RoughnessSlider
        function RoughnessSliderValueChanged(app, event)
            value = app.RoughnessSlider.Value;
            [DAFx_out, FS]= hpsmodelrough('input.wav', value);
            clip= audioplayer(DAFx_out,FS);
            audiowrite('output.wav', DAFx_out, FS);
            clear sound;
            %plot the new waveform
            dt = 1/FS;
            t = 0:dt:(length(DAFx_out)*dt)-dt;
            plot(app.UIAxes,t,DAFx_out); xlabel(app.UIAxes,'Seconds'); ylabel(app.UIAxes,'Amplitude');
            %
            disp('finished change');
            
        end

        % Button pushed function: RecordWavFileButton
        function RecordWavFileButtonPushed(app, event)
            disp('button pressed');
            app.RecordingLamp.Visible='on';
            recObj = audiorecorder(8000, 16, 2);
            recordblocking(recObj, 5);
            app.RecordingLamp.Visible='off';
            myrec= getaudiodata(recObj);
            audiowrite('input.wav', myrec, 8000);
            clip= audioplayer(myrec, 8000);
            clear sound;
            %display the file
            DAFx_out = myrec(:,1);
            dt = 1/8000;
            t = 0:dt:(length(DAFx_out)*dt)-dt;
            plot(app.UIAxes_2,t,DAFx_out); xlabel(app.UIAxes_2,'Seconds'); ylabel(app.UIAxes_2,'Amplitude');
            
            
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            %play the modified audio file
            disp('playing audio file')
            [DAFx_out, FS]= audioread('output.wav');
            clip= audioplayer(DAFx_out, FS);
            clear sound;
            sound(DAFx_out, FS);
        end

        % Value changed function: YoungSlider
        function AgeSliderValueChanged(app, event)
            value = app.YoungSlider.Value;
            [DAFx_out, FS]=audioread('input.wav');
            if value>1 %turn old
                fscale = 2;
                timbremapping = [ 0 FS/2; % input frequency
                    0 FS/2 ]; % output frequency
                vtf = 6.5; % vibrato-tremolo frequency in Hz
                va = 400; % vibrato depth in cents
                td = 3; % tremolo depth in dB
                age=0;
            else %turn young
                age=1;
                fscale = 2;
                timbremapping = [ 0 3600 FS/2; % input frequency
                    0 5000 FS/2 ]; % output frequency
                vtf=0;
                va=0;
                td=0;
                
            end
            [DAFx_out, FS]= age_function('input.wav', fscale, timbremapping, vtf, va, td, age);
            clip= audioplayer(DAFx_out,FS);
            audiowrite('output.wav', DAFx_out, FS);
             %plot the new waveform
            dt = 1/FS;
            t = 0:dt:(length(DAFx_out)*dt)-dt;
            plot(app.UIAxes,t,DAFx_out); xlabel(app.UIAxes,'Seconds'); ylabel(app.UIAxes,'Amplitude');
            disp('finished change');
            clear sound;
            
        end

        % Button pushed function: StopPlaybackButton
        function StopPlaybackButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.RoughnessSlider.Value=0;
            app.RobotSlider.Value=0;
            app.MaleSlider.Value=0.01;
            app.YoungSlider.Value=0;
            [DAFx_out, FS]= audioread('original.wav');
            audiowrite('input.wav', DAFx_out, FS);
            audiowrite('output.wav', DAFx_out, FS);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {186, '1x', 273};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AudioSourceLabel = uilabel(app.LeftPanel);
            app.AudioSourceLabel.Text = 'Audio Source';
            app.UploadWavFileButton = uibutton(app.LeftPanel, 'push');
            app.UploadWavFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadWavFileButtonPushed, true);
            app.UploadWavFileButton.Text = 'Upload Wav File';
            app.RecordWavFileButton = uibutton(app.LeftPanel, 'push');
            app.RecordWavFileButton.ButtonPushedFcn = createCallbackFcn(app, @RecordWavFileButtonPushed, true);
            app.RecordWavFileButton.Text = 'Record Wav File';
            app.RecordingLampLabel = uilabel(app.LeftPanel);
            app.RecordingLampLabel.Visible = 'off';
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.LeftPanel);
            app.RecordingLamp.Visible = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.PlayAudioButton = uibutton(app.CenterPanel, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.WaveformLabel = uilabel(app.CenterPanel);
            app.WaveformLabel.Text = 'Waveform';
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'resulting wav')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            title(app.UIAxes_2, 'original wav')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.StopPlaybackButton = uibutton(app.CenterPanel, 'push');
            app.StopPlaybackButton.ButtonPushedFcn = createCallbackFcn(app, @StopPlaybackButtonPushed, true);
            app.StopPlaybackButton.Text = 'Stop Playback';
            app.RightPanel = uipanel(app.GridLayout);
            app.RoughnessSliderLabel = uilabel(app.RightPanel);
            app.RoughnessSliderLabel.Text = 'Roughness';
            app.RoughnessSlider = uislider(app.RightPanel);
            app.RoughnessSlider.Limits = [0 14];
            app.RoughnessSlider.ValueChangedFcn = createCallbackFcn(app, @RoughnessSliderValueChanged, true);
            app.RobotSliderLabel = uilabel(app.RightPanel);
            app.RobotSliderLabel.Text = 'Robot';
            app.RobotSlider = uislider(app.RightPanel);
            app.RobotSlider.Limits = [0 1];
            app.RobotSlider.ValueChangedFcn = createCallbackFcn(app, @RobotSliderValueChanged, true);
            app.MaleSliderLabel = uilabel(app.RightPanel);
            app.MaleSliderLabel.Text = 'Male';
            app.MaleSlider = uislider(app.RightPanel);
            app.MaleSlider.Limits = [0 2];
            app.MaleSlider.MajorTicks = [0.01 0.5 1 1.5 2];
            app.MaleSlider.ValueChangedFcn = createCallbackFcn(app, @MaleSliderValueChanged, true);
            app.VoiceControlsLabel = uilabel(app.RightPanel);
            app.VoiceControlsLabel.Text = 'Voice Controls';
            app.YoungSliderLabel = uilabel(app.RightPanel);
            app.YoungSliderLabel.Text = 'Young';
            app.YoungSlider = uislider(app.RightPanel);
            app.YoungSlider.Limits = [0 2];
            app.YoungSlider.MajorTicks = [0 0.5 1 1.5 2];
            app.YoungSlider.MajorTickLabels = {'0', '0.5', '1', '1.5', '2'};
            app.YoungSlider.ValueChangedFcn = createCallbackFcn(app, @AgeSliderValueChanged, true);
            app.GenderSliderLabel_2 = uilabel(app.RightPanel);
            app.GenderSliderLabel_2.Text = 'Female';
            app.YoungSliderLabel_2 = uilabel(app.RightPanel);
            app.YoungSliderLabel_2.Text = 'Old';
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = voicemod

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end